classdef Charge_virtuel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PulseGeneratorUIFigure       matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        Panel                        matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        LampeLEDSwitchLabel          matlab.ui.control.Label
        LampeLEDSwitch               matlab.ui.control.Switch
        FourmicroSwitchLabel         matlab.ui.control.Label
        FourmicroSwitch              matlab.ui.control.Switch
        schoircheveuxSwitchLabel     matlab.ui.control.Label
        schoircheveuxSwitch          matlab.ui.control.Switch
        cuiseurrizSwitchLabel        matlab.ui.control.Label
        cuiseurrizSwitch             matlab.ui.control.Switch
        ChauffageSwitchLabel         matlab.ui.control.Label
        ChauffageSwitch              matlab.ui.control.Switch
        OrdinateurKnobLabel          matlab.ui.control.Label
        OrdinateurKnob               matlab.ui.control.Knob
        PlotButton                   matlab.ui.control.Button
        AutoUpdateSwitchLabel        matlab.ui.control.Label
        AutoUpdateSwitch             matlab.ui.control.Switch
        Label_2                      matlab.ui.control.Label
        AutoUpdateLamp               matlab.ui.control.Lamp
        FrequencyEditFieldLabel      matlab.ui.control.Label
        FrequencyEditField           matlab.ui.control.NumericEditField
        SignalLengthsEditFieldLabel  matlab.ui.control.Label
        SignalLengthsEditField       matlab.ui.control.NumericEditField
        RightPanel                   matlab.ui.container.Panel
        PulsePlotUIAxes              matlab.ui.control.UIAxes
        Label_3                      matlab.ui.control.Label
        PlotTypeSwitch               matlab.ui.control.Switch
        PWEditFieldLabel             matlab.ui.control.Label
        PWEditField                  matlab.ui.control.NumericEditField
        SQVAEditFieldLabel           matlab.ui.control.Label
        SQVAEditField                matlab.ui.control.NumericEditField
        cosphiEditFieldLabel         matlab.ui.control.Label
        cosphiEditField              matlab.ui.control.NumericEditField
        PulsePlotUIAxes_2            matlab.ui.control.UIAxes
        CourantLabel                 matlab.ui.control.Label
        TensionLabel                 matlab.ui.control.Label
        IeffiALabel                  matlab.ui.control.Label
        IeffiAEditField              matlab.ui.control.NumericEditField
        UeffiVLabel                  matlab.ui.control.Label
        UeffiVEditField              matlab.ui.control.NumericEditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        autoUpdate
    end
    
    methods (Access = private)
        
        function updatePlot(app)
            
            signalLength = app.SignalLengthsEditField.Value ;
            frequency = app.FrequencyEditField.Value ;
            plotType = app.PlotTypeSwitch.Value ;
            
            
            p = modeledeCharge(app) ;
            t = -signalLength/2:1/frequency:signalLength/2;
            
            s1 = fft(p)/(signalLength*frequency);
            s = 2 * abs( s1( 1:signalLength*frequency/4+1));
            s(1) = s(1)/2;
            fB = frequency/2;
            f = fB * linspace(0, 1, signalLength*frequency/4+1);
%             f = 0:1/(frequency*signalLength):1;
            
            
            if strcmp(plotType,'Time')
                plot(app.PulsePlotUIAxes, t, p);
                xlabel(app.PulsePlotUIAxes,'Time(s)');
                ylabel(app.PulsePlotUIAxes,'Courant(A)');
                
            else
                plot(app.PulsePlotUIAxes, f/2, s);
                xlabel(app.PulsePlotUIAxes,'Frequency(Hz)');
                ylabel(app.PulsePlotUIAxes,'Amplitude(A)');
            end
            u= 325*sin(314*t);
            plot(app.PulsePlotUIAxes_2, t, u);
            xlabel(app.PulsePlotUIAxes_2,'Time(s)');
            ylabel(app.PulsePlotUIAxes_2,'Tension(V)');
        end
        
        function result = modeledeCharge(app)
            
            frequency = app.FrequencyEditField.Value ;
            signalLength = app.SignalLengthsEditField.Value ;
            lampe = app.LampeLEDSwitch.Value ;
            fourmicro = app.FourmicroSwitch.Value ;
            sechoir = app.schoircheveuxSwitch.Value ;
            cuiseur = app.cuiseurrizSwitch.Value ;
            chauffage = app.ChauffageSwitch.Value ;
            ordinateur = app.OrdinateurKnob.Value ;


            t = -signalLength/2:1/frequency:signalLength/2 ;
            y = zeros(1,(frequency * signalLength+1)) ;
            u= 325*sin(314*t);
            
            if(strcmp(lampe,'On'))
                lampe = load('lampe.txt');
                m = size(lampe,1);
                y1 = 0;
                %                 Irmsl = 0;
                while (m)
                    w = lampe(m,:);
                    amp= sqrt(w(2)^2+w(3)^2);
                    theda = atan(w(2)/w(3));
                    y1= y1 + amp*sin(2*3.14*w(1)* t +theda);
                    %                     Irmsl = Irmsl+ amp^2;
                    m = m-1;
                end
                y = y+ y1 ;
                %                 Irms = Irms+sqrt(Irmsl);
            end
            
            if(ordinateur)
                if(ordinateur == 100)
                    ordicharge = load('ordicharge.txt');
                    m = size(ordicharge,1);
                    y2 = 0;
                    while (m)
                        w = ordicharge(m,:);
                        amp= sqrt(w(2)^2+w(3)^2);
                        theda = atan(w(2)/w(3));
                        y2= y2 + amp*sin(2*3.14*w(1)* t +theda);
                        m = m-1;
                    end
                    y = y + y2 ;
                else
                    ordiencharge = load('ordiencharge.txt');
                    m = size(ordiencharge,1);
                    y2 = 0;
                    while (m)
                        w = ordiencharge(m,:);
                        amp= sqrt(w(2)^2+w(3)^2);
                        theda = atan(w(2)/w(3));
                        y2= y2 + amp*sin(2*3.14*w(1)* t +theda);
                        m = m-1;
                    end
                    y = y +y2 ;
                end
            end
            if(strcmp(fourmicro,'On'))
                fourmicro = load('fourmicro.txt');
                m = size(fourmicro,1);
                y6 = 0;
                while (m)
                    w = fourmicro(m,:);
                    amp= sqrt(w(2)^2+w(3)^2);
                    theda = atan(w(2)/w(3));
                    y6= y6 + amp*sin(2*3.14*w(1)* t +theda);
                    m = m-1;
                end
                y = y+ y6 ;
            end
            y = y/1000;
            
            if(strcmp(chauffage,'On'))
                y3 = u/32.04;
                y = y +y3;
            end
            
            if(strcmp(cuiseur,'On'))
                y4 = u/156.23;
                y = y + y4;
            end
            
            if(strcmp(sechoir,'On'))
                y5 = 2.5*sin(314*t-atan(0.46));
                y = y+ y5;
            end


            app.PWEditField.Value = (u*y')/(frequency * signalLength);
            
            s = fft(y);
            Irms = std(s/sqrt(frequency * signalLength)) ;
            Urms = 230;
            app.IeffiAEditField.Value = Irms;
            app.UeffiVEditField.Value = Urms;
            app.SQVAEditField.Value = Irms * Urms;
%             Q^2+D^2 = (app.SQVAEditField.Value)^2-(app.PWEditField.Value)^2;
            
            if(app.SQVAEditField.Value)
                app.cosphiEditField.Value = app.PWEditField.Value / app.SQVAEditField.Value;
            else
                app.cosphiEditField.Value = 0;
            end
            %             sA = abs(s);
            %             sP = angle(s);
            %             s2 = sA.*cos(sP) + 1j*sA.*sin(sP);
            %             y = real(ifft(s2));
            
            result = y ;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AutoUpdateSwitch.Value = 'on';
            app.PlotTypeSwitch.Value = 'Time' ;
            app.PlotButton.Enable = 'off' ;
            app.autoUpdate = 1 ;
            updatePlot(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.PulseGeneratorUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {493, 493};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {493, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            updatePlot(app)
        end

        % Value changed function: AutoUpdateSwitch
        function AutoUpdateSwitchValueChanged(app, event)
            if strcmp(app.AutoUpdateSwitch.Value, 'on')
                app.autoUpdate = 1 ;
                app.PlotButton.Enable = 'off' ;
                app.AutoUpdateLamp.Color = [0 1 0] ;
            else
                app.autoUpdate = 0 ;
                app.PlotButton.Enable = 'on' ;
                app.AutoUpdateLamp.Color = [0.5 0.5 0.5] ;
            end
        end

        % Value changed function: ChauffageSwitch, FourmicroSwitch, 
        % FrequencyEditField, LampeLEDSwitch, OrdinateurKnob, 
        % PlotTypeSwitch, SignalLengthsEditField, cuiseurrizSwitch, 
        % schoircheveuxSwitch
        function KnobValueChanged(app, event)
            if app.autoUpdate
                updatePlot(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PulseGeneratorUIFigure = uifigure('Visible', 'off');
            app.PulseGeneratorUIFigure.Name = 'Pulse Generator';
            app.PulseGeneratorUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.PulseGeneratorUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.LeftPanel);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.LampeLEDSwitchLabel = uilabel(app.GridLayout2);
            app.LampeLEDSwitchLabel.Text = 'Lampe LED';
            app.LampeLEDSwitch = uiswitch(app.GridLayout2, 'slider');
            app.LampeLEDSwitch.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.FourmicroSwitchLabel = uilabel(app.GridLayout2);
            app.FourmicroSwitchLabel.Text = 'Four micro';
            app.FourmicroSwitch = uiswitch(app.GridLayout2, 'slider');
            app.FourmicroSwitch.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.schoircheveuxSwitchLabel = uilabel(app.GridLayout2);
            app.schoircheveuxSwitchLabel.Text = 'séchoir à cheveux ';
            app.schoircheveuxSwitch = uiswitch(app.GridLayout2, 'slider');
            app.schoircheveuxSwitch.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.cuiseurrizSwitchLabel = uilabel(app.GridLayout2);
            app.cuiseurrizSwitchLabel.Text = 'cuiseur à riz';
            app.cuiseurrizSwitch = uiswitch(app.GridLayout2, 'slider');
            app.cuiseurrizSwitch.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.ChauffageSwitchLabel = uilabel(app.GridLayout2);
            app.ChauffageSwitchLabel.Text = 'Chauffage';
            app.ChauffageSwitch = uiswitch(app.GridLayout2, 'slider');
            app.ChauffageSwitch.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.OrdinateurKnobLabel = uilabel(app.GridLayout2);
            app.OrdinateurKnobLabel.Text = 'Ordinateur';
            app.OrdinateurKnob = uiknob(app.GridLayout2, 'continuous');
            app.OrdinateurKnob.MajorTicks = [50 100];
            app.OrdinateurKnob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.OrdinateurKnob.MinorTicks = [50 100];
            app.PlotButton = uibutton(app.LeftPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Enable = 'off';
            app.PlotButton.Text = 'Plot';
            app.AutoUpdateSwitchLabel = uilabel(app.LeftPanel);
            app.AutoUpdateSwitchLabel.Text = 'Auto Update';
            app.AutoUpdateSwitch = uiswitch(app.LeftPanel, 'slider');
            app.AutoUpdateSwitch.Items = {'off', 'on'};
            app.AutoUpdateSwitch.ValueChangedFcn = createCallbackFcn(app, @AutoUpdateSwitchValueChanged, true);
            app.AutoUpdateSwitch.Value = 'on';
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = '';
            app.AutoUpdateLamp = uilamp(app.LeftPanel);
            app.FrequencyEditFieldLabel = uilabel(app.LeftPanel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.FrequencyEditField.Value = 10000;
            app.SignalLengthsEditFieldLabel = uilabel(app.LeftPanel);
            app.SignalLengthsEditFieldLabel.Text = 'Signal Length (s)';
            app.SignalLengthsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SignalLengthsEditField.Limits = [0.02 Inf];
            app.SignalLengthsEditField.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.SignalLengthsEditField.Value = 0.1;
            app.RightPanel = uipanel(app.GridLayout);
            app.PulsePlotUIAxes = uiaxes(app.RightPanel);
            xlabel(app.PulsePlotUIAxes, 'X')
            ylabel(app.PulsePlotUIAxes, 'Y')
            app.Label_3 = uilabel(app.RightPanel);
            app.Label_3.Text = '';
            app.PlotTypeSwitch = uiswitch(app.RightPanel, 'slider');
            app.PlotTypeSwitch.Items = {'Time', 'FFT'};
            app.PlotTypeSwitch.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.PlotTypeSwitch.Value = 'Time';
            app.PWEditFieldLabel = uilabel(app.RightPanel);
            app.PWEditFieldLabel.Text = 'P(W)     ';
            app.PWEditField = uieditfield(app.RightPanel, 'numeric');
            app.SQVAEditFieldLabel = uilabel(app.RightPanel);
            app.SQVAEditFieldLabel.Text = 'S(QVA)     ';
            app.SQVAEditField = uieditfield(app.RightPanel, 'numeric');
            app.cosphiEditFieldLabel = uilabel(app.RightPanel);
            app.cosphiEditFieldLabel.Text = 'cos(phi)';
            app.cosphiEditField = uieditfield(app.RightPanel, 'numeric');
            app.PulsePlotUIAxes_2 = uiaxes(app.RightPanel);
            xlabel(app.PulsePlotUIAxes_2, 'X')
            ylabel(app.PulsePlotUIAxes_2, 'Y')
            app.CourantLabel = uilabel(app.RightPanel);
            app.CourantLabel.Text = 'Courant';
            app.TensionLabel = uilabel(app.RightPanel);
            app.TensionLabel.Text = 'Tension';
            app.IeffiALabel = uilabel(app.RightPanel);
            app.IeffiALabel.Text = 'Ieffi(A)    ';
            app.IeffiAEditField = uieditfield(app.RightPanel, 'numeric');
            app.UeffiVLabel = uilabel(app.RightPanel);
            app.UeffiVLabel.Text = 'Ueffi(V)   ';
            app.UeffiVEditField = uieditfield(app.RightPanel, 'numeric');
            % Show the figure after all components are created
            app.PulseGeneratorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Charge_virtuel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PulseGeneratorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PulseGeneratorUIFigure)
        end
    end
end